classdef HappyOrSad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TextArea           matlab.ui.control.TextArea
        GetImageButton     matlab.ui.control.Button
        EditField          matlab.ui.control.NumericEditField
        Above60SadLabel    matlab.ui.control.Label
        Below60HappyLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            global value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global value;
            value = app.EditField.Value;
            
        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
           global value;
            if(value < 60)
              imshow('Smile.png', 'Parent', app.UIAxes);
              
            elseif (value > 60)
                imshow('Sad.png', 'Parent', app.UIAxes);
            else
                app.TextArea.Value='Error: Enter number above or below 60';
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
      
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Below60HappyLabel = uilabel(app.UIFigure);
            app.Below60HappyLabel.Text = 'Below 60 -> Happy :)';
            app.Above60SadLabel = uilabel(app.UIFigure);
            app.Above60SadLabel.Text = 'Above 60 -> Sad :(';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HappyOrSad(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end