classdef SimpleCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResultEditField               matlab.ui.control.EditField
        ResultEditFieldLabel          matlab.ui.control.Label
        y_axisEditField               matlab.ui.control.EditField
        y_axisEditFieldLabel          matlab.ui.control.Label
        x_axisEditField               matlab.ui.control.EditField
        x_axisEditFieldLabel          matlab.ui.control.Label
        ErrorConsoleEditField_2       matlab.ui.control.EditField
        ErrorConsoleEditField_2Label  matlab.ui.control.Label
        EqualButton_2                 matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        DropDownEquation              matlab.ui.control.DropDown
        ArithemticOpEquation          matlab.ui.control.EditField
        EqualValue                    matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        ErrorConsoleEditField         matlab.ui.control.EditField
        ErrorConsoleEditFieldLabel    matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        ArithmeticOperationsLabel     matlab.ui.control.Label
        EqualButton                   matlab.ui.control.Button
        ArithmeticOp                  matlab.ui.control.EditField
        SecondNumber                  matlab.ui.control.NumericEditField
        FirstNumber                   matlab.ui.control.NumericEditField
        SimpleCalculatorLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            ArithmeticOperation=app.DropDown.Value;
         %   app.ArithmeticOperation=ArithmeticOperation;
            switch(ArithmeticOperation)
                case '+'
                    app.EqualValue.Value=app.FirstNumber.Value+app.SecondNumber.Value;
          
                case '-'
                    app.EqualValue.Value=app.FirstNumber.Value-app.SecondNumber.Value;
                   
                case '*'
                    app.EqualValue.Value=app.FirstNumber.Value*app.SecondNumber.Value;
                case '/'
                    if app.SecondNumber.Value==0
                        app.ErrorConsoleEditField.Value='Can not divide by zero'
                    else
                        app.EqualValue.Value=app.FirstNumber.Value/app.SecondNumber.Value
                    end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.ArithmeticOp.Value=value;
        end

        % Button pushed function: EqualButton_2
        function EqualButton_2Pushed(app, event)
            arr1=app.x_axisEditField.Value;
            arr2=app.y_axisEditField.Value;
            switch(app.DropDownEquation.Value)
                case '+'
                    res1=str2num(arr1(2))+str2num(arr2(2));
                    res2=str2num(arr1(4))+str2num(arr2(4));
                    res3=str2num(arr1(6))+str2num(arr2(6));
                    app.ResultEditField.Value= "["+res1+" "+res2+" "+res3+"]";
                case '-'
                   res1=str2num(arr1(2))-str2num(arr2(2));
                   res2=str2num(arr1(4))-str2num(arr2(4));
                   res3=str2num(arr1(6))-str2num(arr2(6));
                   app.ResultEditField.Value= "["+res1+" "+res2+" "+res3+"]";
                case '*'
                   res1=str2num(arr1(2))*str2num(arr2(2));
                   res2=str2num(arr1(4))*str2num(arr2(4));
                   res3=str2num(arr1(6))*str2num(arr2(6));
                   app.ResultEditField.Value= "["+res1+" "+res2+" "+res3+"]";
                case '/'
                    if (arr2(2)=='0' || arr2(4)=='0' || arr2(6)=='0')
                       app.ErrorConsoleEditField_2.Value='Can not divide by zero'
                    else
                    res1=str2num(arr1(2))/str2num(arr2(2));
                    res2=str2num(arr1(4))/str2num(arr2(4));
                    res3=str2num(arr1(6))/str2num(arr2(6));
                    app.ResultEditField.Value= "["+res1+" "+res2+" "+res3+"]";
                    end
            end
         
        end

        % Value changed function: DropDownEquation
        function DropDownEquationValueChanged(app, event)
            value = app.DropDownEquation.Value;
            app.ArithemticOpEquation.Value=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.FirstNumber = uieditfield(app.UIFigure, 'numeric');
            app.SecondNumber = uieditfield(app.UIFigure, 'numeric');
            app.ArithmeticOp = uieditfield(app.UIFigure, 'text');
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.ArithmeticOperationsLabel = uilabel(app.UIFigure);
            app.ArithmeticOperationsLabel.Text = 'Arithmetic Operations ';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'+', '-', '*', '/'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '+';
            app.ErrorConsoleEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorConsoleEditFieldLabel.Text = 'Error Console';
            app.ErrorConsoleEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.EqualValue = uieditfield(app.UIFigure, 'numeric');
            app.ArithemticOpEquation = uieditfield(app.UIFigure, 'text');
            app.DropDownEquation = uidropdown(app.UIFigure);
            app.DropDownEquation.Items = {'+', '-', '*', '/'};
            app.DropDownEquation.ValueChangedFcn = createCallbackFcn(app, @DropDownEquationValueChanged, true);
            app.DropDownEquation.Value = '+';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.EqualButton_2 = uibutton(app.UIFigure, 'push');
            app.EqualButton_2.ButtonPushedFcn = createCallbackFcn(app, @EqualButton_2Pushed, true);
            app.EqualButton_2.Text = 'Equal';
            app.ErrorConsoleEditField_2Label = uilabel(app.UIFigure);
            app.ErrorConsoleEditField_2Label.Text = 'Error Console';
            app.ErrorConsoleEditField_2 = uieditfield(app.UIFigure, 'text');
            app.x_axisEditFieldLabel = uilabel(app.UIFigure);
            app.x_axisEditFieldLabel.Text = 'x_axis';
            app.x_axisEditField = uieditfield(app.UIFigure, 'text');
            app.y_axisEditFieldLabel = uilabel(app.UIFigure);
            app.y_axisEditFieldLabel.Text = 'y_axis';
            app.y_axisEditField = uieditfield(app.UIFigure, 'text');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end